classdef dialogapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfirmationUIFigure  matlab.ui.Figure
        RedoButton            matlab.ui.control.Button
        KeepButton            matlab.ui.control.Button
        KeepselectedROILabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
     imgBr;
     CallingApp;
     recter;
  
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, firstapp, rectangle)
          app.CallingApp = firstapp;
          app.recter = rectangle;
          
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)

          rect = app.recter;
        save('roidata.mat','rect');   
        app.CallingApp.getterprop.delete;
            
             app.CallingApp.getterprop = figure(1);
            imshow(app.CallingApp.imgBr);
           app.recter = drawrectangle;
          
            
        dialogapp(app.CallingApp,app.recter);
        app.delete;
           
        end

        % Button pushed function: KeepButton
        function KeepButtonPushed(app, event)
             rect = app.recter;
            save('roidata.mat','rect');
            app.CallingApp.getterprop.delete;

            close(app.ConfirmationUIFigure);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfirmationUIFigure = uifigure;
            app.ConfirmationUIFigure.Name = 'Confirmation';
            app.RedoButton = uibutton(app.ConfirmationUIFigure, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Text = 'Redo';
            app.KeepButton = uibutton(app.ConfirmationUIFigure, 'push');
            app.KeepButton.ButtonPushedFcn = createCallbackFcn(app, @KeepButtonPushed, true);
            app.KeepButton.Text = 'Keep';
            app.KeepselectedROILabel = uilabel(app.ConfirmationUIFigure);
            app.KeepselectedROILabel.Text = 'Keep selected ROI?';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dialogapp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConfirmationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfirmationUIFigure)
        end
    end
end